classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        NumerodePuntosEditFieldLabel  matlab.ui.control.Label
        NumerodePuntos                matlab.ui.control.NumericEditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        AZUL                          matlab.ui.control.ToggleButton
        VERDE                         matlab.ui.control.ToggleButton
        AMARILLO                      matlab.ui.control.ToggleButton
        NARANJA                       matlab.ui.control.ToggleButton
        SALMON                        matlab.ui.control.ToggleButton
        ColordelTrazo                 matlab.ui.control.Label
        DIBUJAR                       matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        PlanoComplejo                 matlab.ui.control.UIAxes
        ProyeccionEstereografica      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [sa, sb, sc] = sphere(30);
            plot3(app.ProyeccionEstereografica,sa,sb,sc,'LineStyle',':','color',[0.5,0.5,0.5]); 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {761, 761};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DIBUJAR
        function DIBUJARButtonPushed(app, event)
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
            col = get_col(app.AZUL.Value, ...
                app.VERDE.Value, ...
                app.AMARILLO.Value, ...
                app.NARANJA.Value, ...
                app.SALMON.Value);
            
            axis(app.PlanoComplejo, [-2 2 -2 2]);
            grid(app.PlanoComplejo, 'on');
            
            %           Graficamos los puntos
            fhv = app.UIFigure.HandleVisibility;
            app.UIFigure.HandleVisibility = 'callback';
            set(0, 'CurrentFigure', app.UIFigure)
            
            [x,y] = ginput(app.NumerodePuntos.Value);
            
            app.UIFigure.HandleVisibility = fhv;
            plot(x, y, 'Parent', app.PlanoComplejo,"Color",col);
            
            X = linspace(x(1), x(2), 300); %transicion del paso de X.
            Y = linspace(y(1), y(2), 300); %transicion del paso de Y.
            Z = complex(X, Y);
            [X1,X2,X3] = f_complex_to_riemann(Z);
            
            [sa, sb, sc] = sphere(30);
            plot3(app.ProyeccionEstereografica,sa,sb,sc,'LineStyle',':','color',[0.5,0.5,0.5]);
            hold(app.ProyeccionEstereografica, "on")
            plot3(app.ProyeccionEstereografica,X1,X2,X3,'LineStyle','-','color',col);
            hold(app.ProyeccionEstereografica, "off")
      
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NumerodePuntosEditFieldLabel = uilabel(app.LeftPanel);
            app.NumerodePuntosEditFieldLabel.Text = 'Numero de Puntos';
            app.NumerodePuntos = uieditfield(app.LeftPanel, 'numeric');
            app.NumerodePuntos.Limits = [2 Inf];
            app.NumerodePuntos.RoundFractionalValues = 'on';
            app.NumerodePuntos.ValueDisplayFormat = '%d';
            app.NumerodePuntos.Value = 2;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.AZUL = uitogglebutton(app.ButtonGroup);
            app.AZUL.Text = 'A Z U L';
            app.AZUL.Value = true;
            app.VERDE = uitogglebutton(app.ButtonGroup);
            app.VERDE.Text = 'V E R D E';
            app.AMARILLO = uitogglebutton(app.ButtonGroup);
            app.AMARILLO.Text = 'A M A R I L L O';
            app.NARANJA = uitogglebutton(app.ButtonGroup);
            app.NARANJA.Text = 'N A R A N J A';
            app.SALMON = uitogglebutton(app.ButtonGroup);
            app.SALMON.Text = 'S A L M O N';
            app.ColordelTrazo = uilabel(app.LeftPanel);
            app.ColordelTrazo.Text = 'Color del Trazo';
            app.DIBUJAR = uibutton(app.LeftPanel, 'push');
            app.DIBUJAR.ButtonPushedFcn = createCallbackFcn(app, @DIBUJARButtonPushed, true);
            app.DIBUJAR.Text = 'D I B U J A R';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlanoComplejo = uiaxes(app.RightPanel);
            title(app.PlanoComplejo, 'Plano Complejo')
            xlabel(app.PlanoComplejo, 'X')
            ylabel(app.PlanoComplejo, 'Y')
            zlabel(app.PlanoComplejo, 'Z')
            app.PlanoComplejo.XLim = [-2 2];
            app.PlanoComplejo.YLim = [-2 2];
            app.ProyeccionEstereografica = uiaxes(app.RightPanel);
            title(app.ProyeccionEstereografica, 'Proyección Estereográfica')
            xlabel(app.ProyeccionEstereografica, 'X1')
            ylabel(app.ProyeccionEstereografica, 'X2')
            zlabel(app.ProyeccionEstereografica, 'X3')
            app.ProyeccionEstereografica.XLim = [-1 1];
            app.ProyeccionEstereografica.YLim = [-1 1];
            app.ProyeccionEstereografica.ZLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end